classdef Harmonics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        Harmonic1DropDownLabel    matlab.ui.control.Label
        Harmonic1DropDown         matlab.ui.control.DropDown
        Harmonic2DropDownLabel    matlab.ui.control.Label
        Harmonic2DropDown         matlab.ui.control.DropDown
        IntervalLabel             matlab.ui.control.Label
        FrequencyMultiplierLabel  matlab.ui.control.Label
        UIAxes2                   matlab.ui.control.UIAxes
        UITable                   matlab.ui.control.Table
        IterateButton             matlab.ui.control.Button
    end


    methods (Access = private)
    
 
        function setIntervalLabel(app)
            app.IntervalLabel.Text = sprintf('%s\n%s',"Musically defined distance between two harmonics: ", getDistance(app));
        end
        
        function firstIntegerHarmonic = getFirstIntegerHarmonic(app)
            firstIntegerHarmonic = 1;
            if app.Harmonic1DropDown.Value == "1st"
                firstIntegerHarmonic = extractBefore(app.Harmonic1DropDown.Value, "st");
            elseif app.Harmonic1DropDown.Value == "2nd"
                firstIntegerHarmonic = extractBefore(app.Harmonic1DropDown.Value, "nd");
            elseif app.Harmonic1DropDown.Value == "3rd"
                firstIntegerHarmonic = extractBefore(app.Harmonic1DropDown.Value, "rd");
            else
                firstIntegerHarmonic = extractBefore(app.Harmonic1DropDown.Value, "th");
            end
        end
        
        function secondIntegerHarmonic = getSecondIntegerHarmonic(app)
            if app.Harmonic2DropDown.Value == "1st"
                secondIntegerHarmonic = extractBefore(app.Harmonic2DropDown.Value, "st");
            elseif app.Harmonic2DropDown.Value == "2nd"
                secondIntegerHarmonic = extractBefore(app.Harmonic2DropDown.Value, "nd");
            elseif app.Harmonic2DropDown.Value == "3rd"
                secondIntegerHarmonic = extractBefore(app.Harmonic2DropDown.Value, "rd");
            else
                secondIntegerHarmonic = extractBefore(app.Harmonic2DropDown.Value, "th");
            end
        end
        
        function setMultiplierText(app)
            a = getFirstIntegerHarmonic(app);
            b = getSecondIntegerHarmonic(app);
            app.FrequencyMultiplierLabel.Text = "Frequency Multiplier: " + (str2double(b)/str2double(a));
        end
        
        function refreshAll(app)
            setMultiplierText(app);
            setIntervalLabel(app);
        end
        
        function distance = getDistance(app)
            distance = 'This is not a defined interval in music theory.';
            a = str2double(getFirstIntegerHarmonic(app));
            b = str2double(getSecondIntegerHarmonic(app));
            if b == a*2
                distance = 'Octave';
            elseif b == (a*3)/2
                distance = 'Perfect fifth';
            elseif b == (a*4)/3
                distance = 'Perfect fourth';
            elseif b == (a*5)/4
                distance = 'Major third';
            elseif b == (a*6)/5
                distance = 'Minor third';
            elseif b == (a*9)/8
                distance = 'Whole step';
            end 
       end
        
    end


    methods (Access = private)

        % Value changed function: Harmonic1DropDown
        function Harmonic1DropDownValueChanged(app, event)
            cla(app.UIAxes);
            a = getFirstIntegerHarmonic(app);
            b = getSecondIntegerHarmonic(app);
            refreshAll(app);
            app.UIAxes.XLim = [0 pi];
            app.UIAxes.YLim = [0 1];
            x = linspace(0,pi,1000);
            hold(app.UIAxes);
            plot(app.UIAxes,x,abs(sin(str2double(a)*x)));
            %plot(app.UIAxes,x,abs(sin(str2double(a)*x))*-1);
        end

        % Value changed function: Harmonic2DropDown
        function Harmonic2DropDownValueChanged(app, event)
            cla(app.UIAxes2);
            a = getFirstIntegerHarmonic(app);
            b = getSecondIntegerHarmonic(app);
            refreshAll(app);
            app.UIAxes2.XLim = [0 pi];
            app.UIAxes2.YLim = [0 1];
            x = linspace(0,pi,1000);
            hold(app.UIAxes2);
            plot(app.UIAxes2,x,abs(sin(str2double(b)*x)));
            %plot(app.UIAxes2,x,abs(sin(str2double(b)*x))*-1);
        end

        % Button pushed function: IterateButton
        function IterateButtonPushed(app, event)
            %load('iterationCounter.mat');
            %load('intervalCounter.mat');
            %load('cumulativeMultiplier.mat');
            numericalMultiplier = (str2double(getSecondIntegerHarmonic(app))/str2double(getFirstIntegerHarmonic(app)));
            %if ~strcmp(getDistance(app),'This is not a defined interval in music theory.')
            %app.iterationCounter = app.iterationCounter + 1;
            %if b == a*2
                %app.intervalCounter = app.intervalCounter + 12;
            %elseif b == (a*3)/2
                %app.intervalCounter = app.intervalCounter + 7;
            %elseif b == (a*4)/3
                %app.intervalCounter = app.intervalCounter + 5;
            %elseif b == (a*5)/4
                %app.intervalCounter = app.intervalCounter + 4;
            %elseif b == (a*6)/5
                %app.intervalCounter = app.intervalCounter + 3;
            %elseif b == (a*9)/8
                %app.intervalCounter = app.intervalCounter + 2;
            %end
            %app.cumulativeMultiplier = app.cumulativeMultiplier * numericalMultiplier;
            d = {getDistance(app),1,numericalMultiplier,numericalMultiplier};
            %d = app.UITable.Data + {getDistance(app),app.iterationCounter,numericalMultiplier,numericalMultiplier*app.cumulativeMultiplier};
            app.UITable.Data = d;
            %end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sound Wave Pattern')
            xlabel(app.UIAxes, 'Relative Position Along Wave')
            ylabel(app.UIAxes, 'Relative Amplitude')
            app.UIAxes.ZTick = [0 0.5 1];
            app.Harmonic1DropDownLabel = uilabel(app.UIFigure);
            app.Harmonic1DropDownLabel.Text = 'Harmonic 1';
            app.Harmonic1DropDown = uidropdown(app.UIFigure);
            app.Harmonic1DropDown.Items = {'1st', '2nd', '3rd', '4th', '5th', '6th', '7th', '8th', '9th'};
            app.Harmonic1DropDown.ValueChangedFcn = createCallbackFcn(app, @Harmonic1DropDownValueChanged, true);
            app.Harmonic1DropDown.Value = '1st';
            app.Harmonic2DropDownLabel = uilabel(app.UIFigure);
            app.Harmonic2DropDownLabel.Text = 'Harmonic 2';
            app.Harmonic2DropDown = uidropdown(app.UIFigure);
            app.Harmonic2DropDown.Items = {'1st', '2nd', '3rd', '4th', '5th', '6th', '7th', '8th', '9th'};
            app.Harmonic2DropDown.ValueChangedFcn = createCallbackFcn(app, @Harmonic2DropDownValueChanged, true);
            app.Harmonic2DropDown.Value = '1st';
            app.IntervalLabel = uilabel(app.UIFigure);
            app.IntervalLabel.Text = 'Musically defined distance between two harmonics:';
            app.FrequencyMultiplierLabel = uilabel(app.UIFigure);
            app.FrequencyMultiplierLabel.Text = 'Frequency Multiplier: ';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Sound Wave Pattern')
            xlabel(app.UIAxes2, 'Relative Position Along Wave')
            ylabel(app.UIAxes2, 'Relative Amplitude')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Interval'; 'Iteration'; 'Multiplier'; 'Final Frequency '};
            app.UITable.RowName = {};
            app.IterateButton = uibutton(app.UIFigure, 'push');
            app.IterateButton.ButtonPushedFcn = createCallbackFcn(app, @IterateButtonPushed, true);
            app.IterateButton.Text = 'Iterate';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Harmonics

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end