classdef momentsofinertia < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ObjectDropDownLabel        matlab.ui.control.Label
        ObjectDropDown             matlab.ui.control.DropDown
        MasskgEditFieldLabel       matlab.ui.control.Label
        MassEditField              matlab.ui.control.NumericEditField
        LengthRadiusmLabel         matlab.ui.control.Label
        LengthEditField            matlab.ui.control.NumericEditField
        TypeDropDownLabel          matlab.ui.control.Label
        TypeDropDown               matlab.ui.control.DropDown
        TitleLabel                 matlab.ui.control.Label
        momentlabel                matlab.ui.control.Label
        actualmomentlabel          matlab.ui.control.Label
        MassDistributionLabel      matlab.ui.control.Label
        MassDistributionEditField  matlab.ui.control.EditField
        centerLabel                matlab.ui.control.Label
        CalculateButton            matlab.ui.control.Button
        MDStartEditFieldLabel      matlab.ui.control.Label
        MDStartEditField           matlab.ui.control.NumericEditField
        MDEndEditFieldLabel        matlab.ui.control.Label
        MDEndEditField             matlab.ui.control.NumericEditField
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Panel = uipanel('Title','UIFigure','FontSize',12,...
            %'BackgroundColor','yellow',...
            %'Position',[.25 .1 .67 .67]);
            %uistack(Panel,'down');
        end

        % Value changed function: ObjectDropDown
        function ObjectDropDownValueChanged(app, event)
            object = app.ObjectDropDown.Value;
            if strcmpi('rod',object)
                app.TypeDropDown.Items = {'About Edge','About Midpoint','Non-Uniform'};
            elseif strcmpi('disk',object)
                app.TypeDropDown.Items = {'Hollow','Solid'};
            end
        end

        % Value changed function: LengthEditField
        function LengthEditFieldValueChanged(app, event)
            type = app.TypeDropDown.Value;
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            type = app.TypeDropDown.Value;
            if strcmpi('Non-Uniform',type)
                app.MassDistributionEditField.Enable = 'on';
                app.MassEditField.Enable = 'off';
                app.LengthEditField.Enable  = 'off';
                app.MDStartEditField.Enable = 'on';
                app.MDEndEditField.Enable = 'on';
                app.centerLabel.Text = 'The center of mass of this object is, as calculated by integration: ';
            else
                app.MassDistributionEditField.Enable = 'off';
                app.MassEditField.Enable = 'on';
                app.LengthEditField.Enable  = 'on';
                app.MDStartEditField.Enable = 'off';
                app.MDEndEditField.Enable = 'off';
                app.centerLabel.Text = ' ';
            end
        end

        % Callback function
        function MDEndEditFieldValueChanged(app, event)
            %value = app.MDEndEditField.Value;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            type = app.TypeDropDown.Value;
            length = app.LengthEditField.Value;
            mass = app.MassEditField.Value;
            
            if strcmpi('hollow',type)
                app.momentlabel.Text = 'The moment of inertia of this object is, as calculated by integration: m*r^2';
            elseif strcmpi('solid',type)
                app.momentlabel.Text = 'The moment of inertia of this object is, as calculated by integration: (1/2)*m*r^2';
            elseif strcmpi('About Edge',type)
                app.momentlabel.Text = 'The moment of inertia of this object is, as calculated by integration: (1/12)*m*r^2';
            elseif strcmpi('About Midpoint',type)
                app.momentlabel.Text = 'The moment of inertia of this object is, as calculated by integration: (1/3)*m*r^2';
            elseif strcmpi('Non-Uniform',app.TypeDropDown.Value)
            a = app.MDStartEditField.Value;
            b = app.MDEndEditField.Value;
            length = b-a;
            app.LengthEditField.Value = length;
            
            syms x;
            mass = double(int(app.MassDistributionEditField.Value,x,app.MDStartEditField.Value,app.MDEndEditField.Value));
            app.MassEditField.Value = mass;
            
            massdis = app.MassDistributionEditField.Value;
            centerofmass = double((1/mass)*int(x*massdis,x,a,b));
            n  = num2str(centerofmass,4);
            app.centerLabel.Text = strcat('The center of mass of this object is at, as calculated by integration:',' ',n,'m');
            
            actualmomentofinertia = double(int((abs(x-centerofmass))^2,x,a,b));
            r = num2str(actualmomentofinertia);
            app.momentlabel.Text = '';
            app.actualmomentlabel.Text = strcat('The actual moment of inertia, based on the mass and length/radius is:',' ',r,'kg*m^2');
            end
            
            
            if strcmpi('hollow',type)
                mymoment = string(mass*length^2);
            elseif strcmpi('solid',type)
                mymoment = string((1/2)*mass*length^2);
            elseif strcmpi('About Edge',type)
                mymoment = string((1/12)*mass*length^2);
            elseif strcmpi('About Midpoint',type)
                mymoment = string((1/3)*mass*length^2);
            end
            
            if ~strcmpi(type,'Non-Uniform')
            app.actualmomentlabel.Text = 'The actual moment of inertia, based on the mass and radius of this object is: ' + mymoment;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ObjectDropDownLabel = uilabel(app.UIFigure);
            app.ObjectDropDownLabel.Text = 'Object';
            app.ObjectDropDown = uidropdown(app.UIFigure);
            app.ObjectDropDown.Items = {'Rod', 'Disk'};
            app.ObjectDropDown.ValueChangedFcn = createCallbackFcn(app, @ObjectDropDownValueChanged, true);
            app.ObjectDropDown.Value = 'Disk';
            app.MasskgEditFieldLabel = uilabel(app.UIFigure);
            app.MasskgEditFieldLabel.Text = 'Mass (kg)';
            app.MassEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthRadiusmLabel = uilabel(app.UIFigure);
            app.LengthRadiusmLabel.Text = 'Length/Radius (m)';
            app.LengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthEditField.ValueChangedFcn = createCallbackFcn(app, @LengthEditFieldValueChanged, true);
            app.TypeDropDownLabel = uilabel(app.UIFigure);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.UIFigure);
            app.TypeDropDown.Items = {'Hollow', 'Solid'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'Hollow';
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'Moments of Inertia for the Rotation of Rigid Bodies';
            app.momentlabel = uilabel(app.UIFigure);
            app.momentlabel.Text = 'The moment of inertia of this object is, as calculated by integration:';
            app.actualmomentlabel = uilabel(app.UIFigure);
            app.actualmomentlabel.Text = 'The actual moment of inertia, based on the mass and length/radius is:';
            app.MassDistributionLabel = uilabel(app.UIFigure);
            app.MassDistributionLabel.Text = 'Mass Distribution';
            app.MassDistributionEditField = uieditfield(app.UIFigure, 'text');
            app.MassDistributionEditField.Enable = 'off';
            app.MassDistributionEditField.Value = '0';
            app.centerLabel = uilabel(app.UIFigure);
            app.centerLabel.Text = 'The center of mass of this object is, as calculated by integration: ';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate!';
            app.MDStartEditFieldLabel = uilabel(app.UIFigure);
            app.MDStartEditFieldLabel.Text = 'MD Start';
            app.MDStartEditField = uieditfield(app.UIFigure, 'numeric');
            app.MDStartEditField.Enable = 'off';
            app.MDEndEditFieldLabel = uilabel(app.UIFigure);
            app.MDEndEditFieldLabel.Text = 'MD End';
            app.MDEndEditField = uieditfield(app.UIFigure, 'numeric');
            app.MDEndEditField.Enable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = momentsofinertia

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end